classdef Proyecto < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        ArchivoTab                      matlab.ui.container.Tab
        ReproduciroriginalButton_4      matlab.ui.control.Button
        CargarArchivoButton             matlab.ui.control.Button
        EscojaunarchivoLabel            matlab.ui.control.Label
        InstruccionesButton             matlab.ui.control.Button
        Canal1Tab                       matlab.ui.container.Tab
        DistorcinCheckBox               matlab.ui.control.CheckBox
        VibratoCheckBox                 matlab.ui.control.CheckBox
        EchoDelayCheckBox               matlab.ui.control.CheckBox
        ReverberacinCheckBox            matlab.ui.control.CheckBox
        PhaserCheckBox                  matlab.ui.control.CheckBox
        FrecuenciadecentroEditFieldLabel  matlab.ui.control.Label
        FrecuenciadecentroEditField     matlab.ui.control.NumericEditField
        FactordecalidadEditField_4Label  matlab.ui.control.Label
        FactordecalidadEditField_4      matlab.ui.control.NumericEditField
        FrecuenciadecortealtaEditFieldLabel  matlab.ui.control.Label
        FrecuenciadecortealtaEditField  matlab.ui.control.NumericEditField
        ReproduciroriginalButton        matlab.ui.control.Button
        ReproducirnuevoButton           matlab.ui.control.Button
        FiltroDropDownLabel             matlab.ui.control.Label
        FiltroDropDown                  matlab.ui.control.DropDown
        AplicarefectosButton            matlab.ui.control.Button
        msdeatrasoEditFieldLabel        matlab.ui.control.Label
        msdeatrasoEditField             matlab.ui.control.NumericEditField
        FreqHzSlider_3Label             matlab.ui.control.Label
        FreqHzSlider_3                  matlab.ui.control.Slider
        GananciaKnobLabel               matlab.ui.control.Label
        GananciaKnob                    matlab.ui.control.Knob
        FreqHzEditFieldLabel            matlab.ui.control.Label
        FreqHzEditField                 matlab.ui.control.NumericEditField
        FrecuenciadecortebajaEditFieldLabel  matlab.ui.control.Label
        FrecuenciadecortebajaEditField  matlab.ui.control.NumericEditField
        DelaySlider_2Label              matlab.ui.control.Label
        DelaySlider_2                   matlab.ui.control.Slider
        AplicarfiltroButton             matlab.ui.control.Button
        PararaudioButton                matlab.ui.control.Button
        CargarimpulsoButton             matlab.ui.control.Button
        Canal2Tab                       matlab.ui.container.Tab
        DistorcinCheckBox_2             matlab.ui.control.CheckBox
        VibratoCheckBox_2               matlab.ui.control.CheckBox
        EchoDelayCheckBox_2             matlab.ui.control.CheckBox
        ReverberacinCheckBox_2          matlab.ui.control.CheckBox
        PhaserCheckBox_2                matlab.ui.control.CheckBox
        FrecuenciadecentroEditField_2Label  matlab.ui.control.Label
        FrecuenciadecentroEditField_2   matlab.ui.control.NumericEditField
        FactordecalidadEditField_5Label  matlab.ui.control.Label
        FactordecalidadEditField_5      matlab.ui.control.NumericEditField
        FrecuenciadecortealtaEditField_2Label  matlab.ui.control.Label
        FrecuenciadecortealtaEditField_2  matlab.ui.control.NumericEditField
        ReproduciroriginalButton_2      matlab.ui.control.Button
        ReproducirnuevoButton_2         matlab.ui.control.Button
        FiltroDropDown_2Label           matlab.ui.control.Label
        FiltroDropDown_2                matlab.ui.control.DropDown
        AplicarefectosButton_2          matlab.ui.control.Button
        msdeatrasoEditField_2Label      matlab.ui.control.Label
        msdeatrasoEditField_2           matlab.ui.control.NumericEditField
        GananciaKnob_2Label             matlab.ui.control.Label
        GananciaKnob_2                  matlab.ui.control.Knob
        FreqHzSlider_4Label             matlab.ui.control.Label
        FreqHzSlider_4                  matlab.ui.control.Slider
        FreqHzSlider_2Label             matlab.ui.control.Label
        FreqHzSlider_2                  matlab.ui.control.Slider
        DelaySliderLabel                matlab.ui.control.Label
        DelaySlider                     matlab.ui.control.Slider
        FreqHzEditField_2Label          matlab.ui.control.Label
        FreqHzEditField_2               matlab.ui.control.NumericEditField
        FrecuenciadecortebajaEditField_2Label  matlab.ui.control.Label
        FrecuenciadecortebajaEditField_2  matlab.ui.control.NumericEditField
        AplicarfiltroButton_2           matlab.ui.control.Button
        PararaudioButton_2              matlab.ui.control.Button
        GrficasfiltrosTab               matlab.ui.container.Tab
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        ReproduciroriginalButton_3      matlab.ui.control.Button
        ReproducirnuevoButton_3         matlab.ui.control.Button
        GrficasdeaudioTab               matlab.ui.container.Tab
        UIAxes3                         matlab.ui.control.UIAxes
        UIAxes4                         matlab.ui.control.UIAxes
        ReproduciroriginalButton_5      matlab.ui.control.Button
        ReproducirnuevoButton_4         matlab.ui.control.Button
        GuardarTab                      matlab.ui.container.Tab
        ReproduciroriginalButton_6      matlab.ui.control.Button
        ReproducirnuevoButton_5         matlab.ui.control.Button
        GuardarnuevocomowavButton       matlab.ui.control.Button
        GuardarvariablescomomatButton   matlab.ui.control.Button
    end

    
    methods (Access = private)
        
        function [y2, t2]= delay_funcion(app,x, delay_ms, alfa, Fs)
            %Tiempo de muestreo
            ts = 1/Fs;
            
            %Calculo del numero de muestras que ocupa el delay
            delay_muestra = round((delay_ms/1000)*Fs);
            %Es importante que el tipo de dato de esta operación sea entero
            
            %tamaño de salida de la señal (numero de filas del vector columna)
            N = size(x,1);
        
            %reservar el espacio de memoria (vector columna)
            y2 = zeros(N,1);
            
            %Ecuación de Diferencias
            %Aplicamos la ecuación de diferencias
            for i = 1:N
                if i>delay_muestra
                    y2(i) = x(i) + alfa*x(i-delay_muestra);
                end
                if i<=delay_muestra
                    y2(i) = x(i);
                end
        
            end
        
            %pasamos de muestras a tiempo
            t2 = ts*(0:(N-1));
            t2 = t2.'; %Cambiamos de vector fila a vector columna
        
            %Salidas
            %y2 es la señal procesada
            %t2 es el vector de tiempo para graficar la señal (s)
            
            %Entradas
            %x es la señal de entrada
            %delay_ms es el delay en milisegundos
            %alfa es la ganacia del efecto
            %Fs es la frecuencia de muestreo
            
        end
        
        function y = reverb_funcion(app,x, h)
            %Se hace la convolución
            y = conv(x,h);
            %Normalizamos dividiendo entre el máximo absoluto
            m = max(y);
            g = abs(min(y)); %sacamos el valor absoluto de los extremos
            star = max(m,g); %Averiguamos cuál de los dos es mayor
            y = y/star; %Devolvemos el valor normalizado
        end
        
        function y = vibrato_funcion(app, x, frec, ms, fs)
            %Pasamos a segundos 
            seg = ms / 1000; 
            %Delay 
            delay=round(seg*fs); % Delay base, per o en muestras
            segpulso=round(seg*fs); % modulacion del pulso, en muestras
            l=length(x);
            ancho=2+delay+segpulso*2; % Ancho del delay
            
            % Crear vectores contenedores
            ceros=zeros(ancho,1);
            y=zeros(size(x));
            
            % Creando Efecto
            for n=1:(l-1)
                m = frec/fs;
                alt = sin(m*2*pi*n);
                mod = 1+delay+segpulso*alt;
                i=floor(mod);
                ceros=[x(n);ceros(1:ancho-1)];
                y(n,1)=ceros(i+1)*(mod-i)+ceros(i)*(1-(mod-i));
            end
        end
                
        function y = distorcion_funcion(~, x, fc, fs)
            largo = 1:length(x);
            dist = sin(2*pi*largo*(fc/fs))';
            y = x.*dist;
        end
        function y = phaser_funcion(app ,x, fs)
            %x es el array/vector del audio
            %fs es la frecuencia de muestreo
            delay = 25; %15 son los valores experimentales que suenan bien
            range = 50; %12 aunque se pueden modificar para que el phaser 
            sweep_freq = 0.3125/2; %0.3125 tenga diferentes propiedades
            N = length(x(:,1))-delay-range;
            %reservar el espacio de memoria
            y = zeros(N,1);
            %aplicar ecuación de diferencias
            for i = 1:N
                temp = i - delay - round(range*sin(2*pi*i*sweep_freq/fs));
                %Acá solo nos aseguramos de que la señal siga igual siempre y cuando 
                %i sea positivo porque matlab no admite coeficientes negativos
                %el problema se elimina sumando delay y round() pero esto hace que el
                %sistema deje de ser causal, lo que ocasiona que no se pueda utilizar
                %para procesar audio en tiempo real. Únicamente podría procesar audio
                %cargado a la memoria, en este caso no importa pero lo dejé así 
                %(signo negativo) para
                %utilizar las mismas convenciones que utilizamos en la teoría.
            
                if (temp>0)
                    y(i) = x(i) + x(temp);
                else
                    y(i) = x(i);
                end
            
            end
            %delay es el delay por defecto que tiene la señal
            %range es la amplitud del efecto
            %sweep_freq es la frecuencia con la que cambiamos la frecuencia de
            %corte del notch y el pasabandas.
        end
       
        
        function y = pasabandas(app, x, fa, fb, fs)
            N = 201;
            ganancia = 10^14;
            n = -floor(N/2):floor(N/2);
            w = hamming(N);
            fcb = fa/fs;
            baja = (2*fcb*sinc(2*fcb*n))' .* w;

            fca = fb/fs;
            alta = (sinc(n) - 2*fca*sinc(n.*2*fca))' .* w;

            ybaja = filter(baja, [1], x);
            ybanda = filter(alta, [1], ybaja);
            y = ganancia.*ybanda;
            y = x;
        end
        
       
        function y = freccentro(app, x, fc,q,fs)
            N = 201;
            ganancia = 10^14;
            n = -floor(N/2):floor(N/2);
            w = hamming(N);
            fa= fc/q;
            fb= 1;
            fcb = fa/fs;
            baja = (2.*fcb*sinc(2.*fcb*n))' .* w;

            fca = fb/fs;
            alta = (sinc(n) - 2*fca*sinc(n.*2*fca))' .* w;

            ybaja = filter(baja, (1), x);
            ybanda = filter(alta, (1), ybaja);
            y = ganancia.*ybanda;
            y = x;
            
        end
    end
    

    methods (Access = private)

        % Button pushed function: CargarArchivoButton
        function CargarArchivoButtonPushed(app, event)
            global file;
            global fs;
            global og;
            global canal1;
            global canal2;
            global canales;
            [file] = uigetfile('*.wav;*.mp3');
            %Si no hay archivo el texto le dice al usuario que escoja uno
            if (file ==0)  
                app.EscojaunarchivoLabel.Text = ("Escoja un archivo");                
            %Si sí hay archivo, se ven los datos del archivo y el texto
            %dice el nombre del archivo que se escogio 
            else
                [og,fs]= audioread(file);
                app.EscojaunarchivoLabel.Text = file;
                %Vemos cuantos canales tienen y se guarda en una variable
                canales= size(og,2);
                
          
                %Para graficar el audio original              
                N = fs;
                ogX1=fft(og);
                f = fs*(0:(N/2))/N;
                ogP2 = abs(ogX1)/N;
                ogP1 = ogP2(1:N/2+1);
                ogP1(2:end-1) = 2*ogP1(2:end-1);
                        
                plot(app.UIAxes3, f, ogP1);

                                       
            end
        end

        % Button pushed function: ReproduciroriginalButton, 
        % ReproduciroriginalButton_2, ReproduciroriginalButton_3, 
        % ReproduciroriginalButton_4, ReproduciroriginalButton_5, 
        % ReproduciroriginalButton_6
        function ReproduciroriginalButtonPushed(app, event)
            global fs;
            global og;
            soundsc(og, fs);
        end

        % Button pushed function: AplicarfiltroButton
        function AplicarfiltroButtonPushed(app, event)
            global canales;
            global banda1;
            global og;
            global fs;
            global filtro1;
            global frecentro;
            global calidad;
            global frecortea1;
            global frecorteb1;
        
            
            banda1 = zeros(size(og));
            if (filtro1== "Freq. centro y factor de calidad")
                if (canales == 2)
                    banda1(:,1) = app.freccentro(og(:,1), frecentro, calidad, fs);
                    banda1(:,2) = app.freccentro(og(:,2), frecentro, calidad, fs);
                else
                    banda1 = app.freccentro(og, frecentro, calidad, fs);
                end
  
            end
            if (filtro1== "Frecuencias de corte")
                if (canales == 2)
                    banda1(:,1) = app.pasabandas(og(:,1), frecorteb1, frecortea1, fs);
                    banda1(:,2) = app.pasabandas(og(:,2), frecorteb1, frecortea1, fs);
                else
                    banda1 = app.pasabandas(og, frecorteb1, frecortea1, fs);
                end
            end
            
            
            %Gráfica de respuesta en frecuencia
            N1 = size(banda1,1);
            banda1X1=fft(banda1);
            fbanda1 = fs*(0:(N1/2))/N1;
            banda1P2 = abs(banda1X1)/N1;
            banda1P1 = banda1P2(1:N1/2+1);
            banda1P1(2:end-1) = 2*banda1P1(2:end-1);
                    
            plot(app.UIAxes, fbanda1, mag2db(banda1P1));

            
        end

        % Button pushed function: AplicarfiltroButton_2
        function AplicarfiltroButton_2Pushed(app, event)
            global canales;
            global banda2;
            global og;
            global fs;
            global filtro2;
            global frecentro2;
            global calidad2;
            global frecortea2;
            global frecorteb2;
        
            
            banda2 = zeros(size(og));
            if (filtro2== "Freq. centro y factor de calidad")
                if (canales == 2)
                    banda2(:,1) = app.freccentro(og(:,1), frecentro2, calidad2, fs);
                    banda2(:,2) = app.freccentro(og(:,2), frecentro2, calidad2, fs);
                else
                    banda2 = app.freccentro(og, frecentro2, calidad2, fs);
                end
  
            end
            if (filtro2== "Frecuencias de corte")
                if (canales == 2)
                    banda2(:,1) = app.pasabandas(og(:,1), frecorteb2, frecortea2, fs);
                    banda2(:,2) = app.pasabandas(og(:,2), frecorteb2, frecortea2, fs);
                else
                    banda2 = app.pasabandas(og, frecorteb2, frecortea2, fs);
                end
            end
            
            %Gráficas de respuesta en frecuencia
            N2 = size(banda2,1);
            banda2X1=fft(banda2);
            fbanda2 = fs*(0:(N2/2))/N2;
            banda2P2 = abs(banda2X1)/N2;
            banda2P1 = banda2P2(1:N2/2+1);
            banda2P1(2:end-1) = 2*banda2P1(2:end-1);
                    
            plot(app.UIAxes2, fbanda2, mag2db(banda2P1));
        end

        % Value changed function: FiltroDropDown
        function FiltroDropDownValueChanged(app, event)
            global filtro1;
            filtro1 = app.FiltroDropDown.Value;  

            
        end

        % Value changed function: FiltroDropDown_2
        function FiltroDropDown_2ValueChanged(app, event)
            global filtro2;
            filtro2 = app.FiltroDropDown_2.Value;  

        end

        % Value changed function: FrecuenciadecentroEditField
        function FrecuenciadecentroEditFieldValueChanged(app, event)
            global frecentro;
            frecentro = app.FrecuenciadecentroEditField.Value;
            
        end

        % Value changed function: FactordecalidadEditField_4
        function FactordecalidadEditField_4ValueChanged(app, event)
            global calidad;
            calidad = app.FactordecalidadEditField_4.Value;
            
        end

        % Value changed function: FrecuenciadecortealtaEditField
        function FrecuenciadecortealtaEditFieldValueChanged(app, event)
            global frecortea1;
            frecortea1 = app.FrecuenciadecortealtaEditField.Value;
           
        end

        % Value changed function: FrecuenciadecortebajaEditField
        function FrecuenciadecortebajaEditFieldValueChanged(app, event)
            global frecorteb1
            frecorteb1 = app.FrecuenciadecortebajaEditField.Value;
            
        end

        % Value changed function: FrecuenciadecentroEditField_2
        function FrecuenciadecentroEditField_2ValueChanged(app, event)
            global frecentro2;
            frecentro2 = app.FrecuenciadecentroEditField_2.Value;
        end

        % Value changed function: FactordecalidadEditField_5
        function FactordecalidadEditField_5ValueChanged(app, event)
            global calidad2;
            calidad2 = app.FactordecalidadEditField_5.Value;
            
        end

        % Value changed function: FrecuenciadecortealtaEditField_2
        function FrecuenciadecortealtaEditField_2ValueChanged(app, event)
            global frecortea2;
            frecortea2 = app.FrecuenciadecortealtaEditField_2.Value;
            
        end

        % Value changed function: FrecuenciadecortebajaEditField_2
        function FrecuenciadecortebajaEditField_2ValueChanged(app, event)
            global frecorteb2
            frecorteb2 = app.FrecuenciadecortebajaEditField_2.Value;
            
        end

        % Value changed function: DistorcinCheckBox
        function DistorcinCheckBoxValueChanged(app, event)
            %Filtro distorción 1
            global distorcion1;
            value = app.DistorcinCheckBox.Value;
            if (value==1)
                distorcion1=1;
            else
                distorcion1=0;
            end
           
            
        end

        % Value changed function: VibratoCheckBox
        function VibratoCheckBoxValueChanged(app, event)
             %Filtro vibrato 1
            global vibrato1;
            value = app.DistorcinCheckBox.Value;
            if (value==1)
                vibrato1=1;
            else
                vibrato1=0;
            end
        end

        % Value changed function: ReverberacinCheckBox
        function ReverberacinCheckBoxValueChanged(app, event)
             %Filtro reverb 1
            global reverb1;
            value = app.DistorcinCheckBox.Value;
            if (value==1)
                reverb1=1;
            else
                reverb1=0;
            end
            
        end

        % Value changed function: PhaserCheckBox
        function PhaserCheckBoxValueChanged(app, event)
            %Filtro phaser 1
            global phaser1;
            value = app.DistorcinCheckBox.Value;
            if (value==1)
                phaser1=1;
            else
                phaser1=0;
            end
        end

        % Value changed function: EchoDelayCheckBox
        function EchoDelayCheckBoxValueChanged(app, event)
            %Filtro echo 1
            global echo1;
            value = app.DistorcinCheckBox.Value;
            if (value==1)
                echo1=1;
            else
                echo1=0;
            end


        end

        % Value changed function: DistorcinCheckBox_2
        function DistorcinCheckBox_2ValueChanged(app, event)
            %Filtro distorcion 2
            global distorcion2;
            value = app.DistorcinCheckBox.Value;
            if (value==1)
                distorcion2=1;
            else
                distorcion2=0;
            end
            
        end

        % Value changed function: VibratoCheckBox_2
        function VibratoCheckBox_2ValueChanged(app, event)
            %Filtro vibrato 2
            global vibrato2;
            value = app.DistorcinCheckBox.Value;
            if (value==1)
                vibrato2=1;
            else
                vibrato2=0;
            end


        end

        % Value changed function: ReverberacinCheckBox_2
        function ReverberacinCheckBox_2ValueChanged(app, event)
            %Filtro reverberacion 2
            global reverb2;
            value = app.DistorcinCheckBox.Value;
            if (value==1)
                reverb2=1;
            else
                reverb2=0;
            end
        end

        % Value changed function: PhaserCheckBox_2
        function PhaserCheckBox_2ValueChanged(app, event)
            %Filtro phaser 2
            global phaser2;
            value = app.DistorcinCheckBox.Value;
            if (value==1)
                phaser2=1;
            else
                phaser2=0;
            end

        end

        % Value changed function: EchoDelayCheckBox_2
        function EchoDelayCheckBox_2ValueChanged(app, event)
            %Filtro echo 2
            global echo2;
            value = app.DistorcinCheckBox.Value;
            if (value==1)
                echo2=1;
            else
                echo2=0;
            end

        end

        % Button pushed function: AplicarefectosButton
        function AplicarefectosButtonPushed(app, event)
            %Probar efectos 1
            %Sumar los ys de los efectos seleccionados y reproducir audio
            %del canal 1 modificado
            global fs;
            global og;
            global banda1;
            global distorcion1;
            global vibrato1;
            global reverb1;
            global phaser1;
            global echo1;
            global nuevabanda1;
            global canales;
            global usdis1;
            global usvib1;
            global usrev1;
            global uspha1;
            global usdel1;
            global dist_out1;
            global vib_out1;
            global rev_out1;
            global pha_out1;
            global del_out1;
            global impulso1;
            
                                   
            nuevabanda1=banda1;
            
            if (canales==2)
                if (distorcion1==1)
                    dist_out1 = app.distorcion_funcion(nuevabanda1, usdis1, fs);            
                    nuevabanda1=dist_out1;
                end
                if (vibrato1==1)
                    ms=500;
                    vib_out1 = app.vibrato_funcion(nuevabanda1, usvib1, ms, fs);
                    nuevabanda1=vib_out1;
                end
                if (reverb1==1)
                    rev_out1 = app.reverb_funcion(nuevabanda1, impulso1);
                    nuevabanda1=rev_out1;
                end    
                if (phaser1==1)
                    pha_out1 = app.phaser_funcion(nuevabanda1, fs);
                    nuevabanda1=pha_out1;
                end
                if (echo1==1)
                  alpha=0.8;              
                  del_out1 = app.delay_funcion(nuevabanda1, usdel1, alpha, fs);
                  nuevabanda1=del_out1;
                end
            end
               
            if (canales==1)
                if (distorcion1==1)
                    dist_out1 = app.distorcion_funcion(nuevabanda1, usdis1, fs);
                    nuevabanda1=dist_out1;
                end
                if (vibrato1==1)
                    ms=500;
                    vib_out1 = app.vibrato_funcion(nuevabanda1, usvib1, ms, fs);
                    nuevabanda1=vib_out1;
                end
                if (reverb1==1)
                    rev_out1 = app.reverb_funcion(nuevabanda1, impulso);
                    nuevabanda1=rev_out1;
                end
                if (phaser1==1)                                
                    pha_out1 = app.reverb_funcion(nuevabanda1, fs);
                    nuevabanda1=pha_out1;
                end                
                if (echo1==1)
                    alpha=0.8;
                    del_out1 = app.reverb_funcion(nuevabanda1, usdel1, alpha, fs);
                    nuevabanda1=del_out1;
                end  
            end  
            
            %Graficamos la señal procesada
            N5 = size(nuevabanda1,1);
            nuevabanda1X1=fft(nuevabanda1);
            fnuevabanda1 = fs*(0:(N5/2))/N5;
            nuevabanda1P2 = abs(nuevabanda1X1)/N5;
            nuevabanda1P1 = nuevabanda1P2(1:N5/2+1);
            nuevabanda1P1(2:end-1) = 2*nuevabanda1P1(2:end-1);
                    
            plot(app.UIAxes4, fnuevabanda1, mag2db(nuevabanda1P1));

        end

        % Button pushed function: AplicarefectosButton_2
        function AplicarefectosButton_2Pushed(app, event)
            %Probar efectos 2
            %Sumar los ys de los efectos seleccionados y reproducir audio
            %del canal 1 modificado
            global fs;
            global banda2;
            global distorcion2;
            global vibrato2;
            global reverb2;
            global phaser2;
            global echo2;
            global nuevabanda2;
            global canales;
            global usdis2;
            global usvib2;
            global usrev2;
            global uspha2;
            global usdel2;
            global dist_out2;
            global vib_out2;
            global rev_out2;
            global pha_out2;
            global del_out2;
                                               
            nuevabanda2=banda2;
            
            if (canales==2)
                if (distorcion2==1)
                    dist_out2 = app.distorcion_funcion(nuevabanda2, usdis2, fs);            
                    dist_out2=nuevabanda2;
                end
                if (vibrato2==1)
                    ms=500;
                    vib_out2 = app.vibrato_funcion(nuevabanda2, usvib2, ms, fs);
                    vib_out2=nuevabanda2;
                end
                if (reverb2==1)
                    rev_out2 = app.reverb_funcion(nuevabanda2, usrev2);
                    rev_out2=nuevabanda2;
                end    
                if (phaser2==1)
                    pha_out2 = app.phaser_funcion(nuevabanda2, fs);
                    pha_out2=nuevabanda2;
                end
                if (echo2==1)
                  alpha=0.8;              
                  del_out2 = app.delay_funcion(nuevabanda2, usdel2, alpha, fs);
                  del_out2=nuevabanda2;
                end
            end


        end

        % Value changed function: FreqHzEditField
        function FreqHzEditFieldValueChanged(app, event)
            global usdis1;
            usdis1 = app.FreqHzEditField.Value;
            
        end

        % Callback function
        function FreqHzSliderValueChanged(app, event)
            global usrev1;
            usrev1 = app.FreqHzSlider.Value;
            
        end

        % Value changed function: FreqHzEditField_2
        function FreqHzEditField_2ValueChanged(app, event)
            global usdis2;
            usdis2 = app.FreqHzEditField_2.Value;
            
        end

        % Value changed function: FreqHzSlider_2
        function FreqHzSlider_2ValueChanged(app, event)
            global usrev2;
            usrev2 = app.FreqHzSlider_2.Value;
            
        end

        % Value changed function: msdeatrasoEditField
        function msdeatrasoEditFieldValueChanged(app, event)
            global usdel1;
            usdel1 = app.msdeatrasoEditField.Value;
            
        end

        % Value changed function: msdeatrasoEditField_2
        function msdeatrasoEditField_2ValueChanged(app, event)
            global usdel2;
            usdel2 = app.msdeatrasoEditField_2.Value;
            
        end

        % Value changed function: FreqHzSlider_3
        function FreqHzSlider_3ValueChanged(app, event)
            global usvib1;
            usvib1 = app.FreqHzSlider_3.Value;
            
        end

        % Value changed function: FreqHzSlider_4
        function FreqHzSlider_4ValueChanged(app, event)
            global usvib2;
            usvib2 = app.FreqHzSlider_4.Value;
        end

        % Value changed function: DelaySlider_2
        function DelaySlider_2ValueChanged(app, event)
            global uspha1;
            uspha1 = app.DelaySlider_2.Value;
            
        end

        % Value changed function: DelaySlider
        function DelaySliderValueChanged(app, event)
            global uspha2;
            uspha2 = app.DelaySlider.Value;
            
        end

        % Value changed function: GananciaKnob
        function GananciaKnobValueChanged(app, event)
            global ganancia1;
            ganancia1 = app.GananciaKnob.Value;
            
        end

        % Value changed function: GananciaKnob_2
        function GananciaKnob_2ValueChanged(app, event)
            global ganancia2;
            ganancia2 = app.GananciaKnob_2.Value;
            
        end

        % Button pushed function: InstruccionesButton
        function InstruccionesButtonPushed(app, event)
            open README.txt;
        end

        % Button pushed function: ReproducirnuevoButton
        function ReproducirnuevoButtonPushed(app, event)
            %Nuevo en banda 1
            global fs;
            global nuevabanda1;
            soundsc(nuevabanda1, fs);
        end

        % Button pushed function: ReproducirnuevoButton_2
        function ReproducirnuevoButton_2Pushed(app, event)
            %Nuevo en banda 2
            global fs;
            global nuevabanda2;
            soundsc(nuevabanda2, fs);
        end

        % Button pushed function: PararaudioButton, PararaudioButton_2
        function PararaudioButtonPushed(app, event)
            clear sound;
        end

        % Button pushed function: ReproducirnuevoButton_3
        function ReproducirnuevoButton_3Pushed(app, event)
            global new;
            global nuevabanda1;
            global nuevabanda2;
            global fs;
            global og;
            new=zeros(size(og));
            new(:,1)=nuevabanda1;
            %new(:,2)= nuevabanda2;


            soundsc(new,fs);
        end

        % Button pushed function: CargarimpulsoButton
        function CargarimpulsoButtonPushed(app, event)
            global impulso1;
            global impulso1file;
            global impulso1fs;
            [impulso1file] = uigetfile('*.wav;*.mp3');
            if (impulso1file ~=0)
                [impulso1,impulso1fs]= audioread(impulso1file);
                app.CargarimpulsoButton.Text= impulso1file;
            end  
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ArchivoTab = uitab(app.TabGroup);
            app.ArchivoTab.Title = 'Archivo';
            app.ReproduciroriginalButton_4 = uibutton(app.ArchivoTab, 'push');
            app.ReproduciroriginalButton_4.ButtonPushedFcn = createCallbackFcn(app, @ReproduciroriginalButtonPushed, true);
            app.ReproduciroriginalButton_4.Text = 'Reproducir original';
            app.CargarArchivoButton = uibutton(app.ArchivoTab, 'push');
            app.CargarArchivoButton.ButtonPushedFcn = createCallbackFcn(app, @CargarArchivoButtonPushed, true);
            app.CargarArchivoButton.Text = 'Cargar Archivo';
            app.EscojaunarchivoLabel = uilabel(app.ArchivoTab);
            app.EscojaunarchivoLabel.Text = {'Escoja un archivo'; ''};
            app.InstruccionesButton = uibutton(app.ArchivoTab, 'push');
            app.InstruccionesButton.ButtonPushedFcn = createCallbackFcn(app, @InstruccionesButtonPushed, true);
            app.InstruccionesButton.Text = 'Instrucciones';
            app.Canal1Tab = uitab(app.TabGroup);
            app.Canal1Tab.Title = 'Canal 1';
            app.DistorcinCheckBox = uicheckbox(app.Canal1Tab);
            app.DistorcinCheckBox.ValueChangedFcn = createCallbackFcn(app, @DistorcinCheckBoxValueChanged, true);
            app.DistorcinCheckBox.Text = 'Distorción';
            app.VibratoCheckBox = uicheckbox(app.Canal1Tab);
            app.VibratoCheckBox.ValueChangedFcn = createCallbackFcn(app, @VibratoCheckBoxValueChanged, true);
            app.VibratoCheckBox.Text = 'Vibrato';
            app.EchoDelayCheckBox = uicheckbox(app.Canal1Tab);
            app.EchoDelayCheckBox.ValueChangedFcn = createCallbackFcn(app, @EchoDelayCheckBoxValueChanged, true);
            app.EchoDelayCheckBox.Text = 'Echo (Delay)';
            app.ReverberacinCheckBox = uicheckbox(app.Canal1Tab);
            app.ReverberacinCheckBox.ValueChangedFcn = createCallbackFcn(app, @ReverberacinCheckBoxValueChanged, true);
            app.ReverberacinCheckBox.Text = 'Reverberación';
            app.PhaserCheckBox = uicheckbox(app.Canal1Tab);
            app.PhaserCheckBox.ValueChangedFcn = createCallbackFcn(app, @PhaserCheckBoxValueChanged, true);
            app.PhaserCheckBox.Text = 'Phaser ';
            app.FrecuenciadecentroEditFieldLabel = uilabel(app.Canal1Tab);
            app.FrecuenciadecentroEditFieldLabel.Text = 'Frecuencia de centro ';
            app.FrecuenciadecentroEditField = uieditfield(app.Canal1Tab, 'numeric');
            app.FrecuenciadecentroEditField.ValueChangedFcn = createCallbackFcn(app, @FrecuenciadecentroEditFieldValueChanged, true);
            app.FactordecalidadEditField_4Label = uilabel(app.Canal1Tab);
            app.FactordecalidadEditField_4Label.Text = 'Factor de calidad';
            app.FactordecalidadEditField_4 = uieditfield(app.Canal1Tab, 'numeric');
            app.FactordecalidadEditField_4.ValueChangedFcn = createCallbackFcn(app, @FactordecalidadEditField_4ValueChanged, true);
            app.FrecuenciadecortealtaEditFieldLabel = uilabel(app.Canal1Tab);
            app.FrecuenciadecortealtaEditFieldLabel.Text = 'Frecuencia de corte alta';
            app.FrecuenciadecortealtaEditField = uieditfield(app.Canal1Tab, 'numeric');
            app.FrecuenciadecortealtaEditField.ValueChangedFcn = createCallbackFcn(app, @FrecuenciadecortealtaEditFieldValueChanged, true);
            app.ReproduciroriginalButton = uibutton(app.Canal1Tab, 'push');
            app.ReproduciroriginalButton.ButtonPushedFcn = createCallbackFcn(app, @ReproduciroriginalButtonPushed, true);
            app.ReproduciroriginalButton.Text = 'Reproducir original';
            app.ReproducirnuevoButton = uibutton(app.Canal1Tab, 'push');
            app.ReproducirnuevoButton.ButtonPushedFcn = createCallbackFcn(app, @ReproducirnuevoButtonPushed, true);
            app.ReproducirnuevoButton.Text = 'Reproducir nuevo';
            app.FiltroDropDownLabel = uilabel(app.Canal1Tab);
            app.FiltroDropDownLabel.Text = 'Filtro';
            app.FiltroDropDown = uidropdown(app.Canal1Tab);
            app.FiltroDropDown.Items = {'Freq. centro y factor de calidad', 'Frecuencias de corte'};
            app.FiltroDropDown.ValueChangedFcn = createCallbackFcn(app, @FiltroDropDownValueChanged, true);
            app.FiltroDropDown.Value = 'Freq. centro y factor de calidad';
            app.AplicarefectosButton = uibutton(app.Canal1Tab, 'push');
            app.AplicarefectosButton.ButtonPushedFcn = createCallbackFcn(app, @AplicarefectosButtonPushed, true);
            app.AplicarefectosButton.Text = 'Aplicar efectos';
            app.msdeatrasoEditFieldLabel = uilabel(app.Canal1Tab);
            app.msdeatrasoEditFieldLabel.Text = 'ms de atraso';
            app.msdeatrasoEditField = uieditfield(app.Canal1Tab, 'numeric');
            app.msdeatrasoEditField.ValueChangedFcn = createCallbackFcn(app, @msdeatrasoEditFieldValueChanged, true);
            app.FreqHzSlider_3Label = uilabel(app.Canal1Tab);
            app.FreqHzSlider_3Label.Text = 'Freq.(Hz)';
            app.FreqHzSlider_3 = uislider(app.Canal1Tab);
            app.FreqHzSlider_3.Limits = [0 10];
            app.FreqHzSlider_3.ValueChangedFcn = createCallbackFcn(app, @FreqHzSlider_3ValueChanged, true);
            app.GananciaKnobLabel = uilabel(app.Canal1Tab);
            app.GananciaKnobLabel.Text = 'Ganancia';
            app.GananciaKnob = uiknob(app.Canal1Tab, 'continuous');
            app.GananciaKnob.Limits = [0 1];
            app.GananciaKnob.ValueChangedFcn = createCallbackFcn(app, @GananciaKnobValueChanged, true);
            app.FreqHzEditFieldLabel = uilabel(app.Canal1Tab);
            app.FreqHzEditFieldLabel.Text = 'Freq. (Hz) ';
            app.FreqHzEditField = uieditfield(app.Canal1Tab, 'numeric');
            app.FreqHzEditField.ValueChangedFcn = createCallbackFcn(app, @FreqHzEditFieldValueChanged, true);
            app.FrecuenciadecortebajaEditFieldLabel = uilabel(app.Canal1Tab);
            app.FrecuenciadecortebajaEditFieldLabel.Text = 'Frecuencia de corte baja';
            app.FrecuenciadecortebajaEditField = uieditfield(app.Canal1Tab, 'numeric');
            app.FrecuenciadecortebajaEditField.ValueChangedFcn = createCallbackFcn(app, @FrecuenciadecortebajaEditFieldValueChanged, true);
            app.DelaySlider_2Label = uilabel(app.Canal1Tab);
            app.DelaySlider_2Label.Text = {'Delay'; ''};
            app.DelaySlider_2 = uislider(app.Canal1Tab);
            app.DelaySlider_2.Limits = [0 25];
            app.DelaySlider_2.ValueChangedFcn = createCallbackFcn(app, @DelaySlider_2ValueChanged, true);
            app.DelaySlider_2.MinorTicks = [0 2.5 5 7.5 10 12.5 15 17.5 20 22.5 25];
            app.AplicarfiltroButton = uibutton(app.Canal1Tab, 'push');
            app.AplicarfiltroButton.ButtonPushedFcn = createCallbackFcn(app, @AplicarfiltroButtonPushed, true);
            app.AplicarfiltroButton.Text = 'Aplicar filtro';
            app.PararaudioButton = uibutton(app.Canal1Tab, 'push');
            app.PararaudioButton.ButtonPushedFcn = createCallbackFcn(app, @PararaudioButtonPushed, true);
            app.PararaudioButton.Text = 'Parar audio';
            app.CargarimpulsoButton = uibutton(app.Canal1Tab, 'push');
            app.CargarimpulsoButton.ButtonPushedFcn = createCallbackFcn(app, @CargarimpulsoButtonPushed, true);
            app.CargarimpulsoButton.Text = 'Cargar impulso';
            app.Canal2Tab = uitab(app.TabGroup);
            app.Canal2Tab.Title = 'Canal 2';
            app.DistorcinCheckBox_2 = uicheckbox(app.Canal2Tab);
            app.DistorcinCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @DistorcinCheckBox_2ValueChanged, true);
            app.DistorcinCheckBox_2.Text = 'Distorción';
            app.VibratoCheckBox_2 = uicheckbox(app.Canal2Tab);
            app.VibratoCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @VibratoCheckBox_2ValueChanged, true);
            app.VibratoCheckBox_2.Text = 'Vibrato';
            app.EchoDelayCheckBox_2 = uicheckbox(app.Canal2Tab);
            app.EchoDelayCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @EchoDelayCheckBox_2ValueChanged, true);
            app.EchoDelayCheckBox_2.Text = 'Echo (Delay)';
            app.ReverberacinCheckBox_2 = uicheckbox(app.Canal2Tab);
            app.ReverberacinCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @ReverberacinCheckBox_2ValueChanged, true);
            app.ReverberacinCheckBox_2.Text = 'Reverberación';
            app.PhaserCheckBox_2 = uicheckbox(app.Canal2Tab);
            app.PhaserCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @PhaserCheckBox_2ValueChanged, true);
            app.PhaserCheckBox_2.Text = 'Phaser ';
            app.FrecuenciadecentroEditField_2Label = uilabel(app.Canal2Tab);
            app.FrecuenciadecentroEditField_2Label.Text = 'Frecuencia de centro ';
            app.FrecuenciadecentroEditField_2 = uieditfield(app.Canal2Tab, 'numeric');
            app.FrecuenciadecentroEditField_2.ValueChangedFcn = createCallbackFcn(app, @FrecuenciadecentroEditField_2ValueChanged, true);
            app.FactordecalidadEditField_5Label = uilabel(app.Canal2Tab);
            app.FactordecalidadEditField_5Label.Text = 'Factor de calidad';
            app.FactordecalidadEditField_5 = uieditfield(app.Canal2Tab, 'numeric');
            app.FactordecalidadEditField_5.ValueChangedFcn = createCallbackFcn(app, @FactordecalidadEditField_5ValueChanged, true);
            app.FrecuenciadecortealtaEditField_2Label = uilabel(app.Canal2Tab);
            app.FrecuenciadecortealtaEditField_2Label.Text = 'Frecuencia de corte alta';
            app.FrecuenciadecortealtaEditField_2 = uieditfield(app.Canal2Tab, 'numeric');
            app.FrecuenciadecortealtaEditField_2.ValueChangedFcn = createCallbackFcn(app, @FrecuenciadecortealtaEditField_2ValueChanged, true);
            app.ReproduciroriginalButton_2 = uibutton(app.Canal2Tab, 'push');
            app.ReproduciroriginalButton_2.ButtonPushedFcn = createCallbackFcn(app, @ReproduciroriginalButtonPushed, true);
            app.ReproduciroriginalButton_2.Text = 'Reproducir original';
            app.ReproducirnuevoButton_2 = uibutton(app.Canal2Tab, 'push');
            app.ReproducirnuevoButton_2.ButtonPushedFcn = createCallbackFcn(app, @ReproducirnuevoButton_2Pushed, true);
            app.ReproducirnuevoButton_2.Text = 'Reproducir nuevo';
            app.FiltroDropDown_2Label = uilabel(app.Canal2Tab);
            app.FiltroDropDown_2Label.Text = 'Filtro ';
            app.FiltroDropDown_2 = uidropdown(app.Canal2Tab);
            app.FiltroDropDown_2.Items = {'Freq. centro y factor de calidad', 'Frecuencias de corte'};
            app.FiltroDropDown_2.ValueChangedFcn = createCallbackFcn(app, @FiltroDropDown_2ValueChanged, true);
            app.FiltroDropDown_2.Value = 'Freq. centro y factor de calidad';
            app.AplicarefectosButton_2 = uibutton(app.Canal2Tab, 'push');
            app.AplicarefectosButton_2.ButtonPushedFcn = createCallbackFcn(app, @AplicarefectosButton_2Pushed, true);
            app.AplicarefectosButton_2.Text = 'Aplicar efectos';
            app.msdeatrasoEditField_2Label = uilabel(app.Canal2Tab);
            app.msdeatrasoEditField_2Label.Text = 'ms de atraso';
            app.msdeatrasoEditField_2 = uieditfield(app.Canal2Tab, 'numeric');
            app.msdeatrasoEditField_2.ValueChangedFcn = createCallbackFcn(app, @msdeatrasoEditField_2ValueChanged, true);
            app.GananciaKnob_2Label = uilabel(app.Canal2Tab);
            app.GananciaKnob_2Label.Text = 'Ganancia';
            app.GananciaKnob_2 = uiknob(app.Canal2Tab, 'continuous');
            app.GananciaKnob_2.Limits = [0 1];
            app.GananciaKnob_2.ValueChangedFcn = createCallbackFcn(app, @GananciaKnob_2ValueChanged, true);
            app.FreqHzSlider_4Label = uilabel(app.Canal2Tab);
            app.FreqHzSlider_4Label.Text = 'Freq. (Hz)';
            app.FreqHzSlider_4 = uislider(app.Canal2Tab);
            app.FreqHzSlider_4.Limits = [0 10];
            app.FreqHzSlider_4.ValueChangedFcn = createCallbackFcn(app, @FreqHzSlider_4ValueChanged, true);
            app.FreqHzSlider_2Label = uilabel(app.Canal2Tab);
            app.FreqHzSlider_2Label.Text = 'Freq. (Hz)';
            app.FreqHzSlider_2 = uislider(app.Canal2Tab);
            app.FreqHzSlider_2.Limits = [0 5];
            app.FreqHzSlider_2.ValueChangedFcn = createCallbackFcn(app, @FreqHzSlider_2ValueChanged, true);
            app.DelaySliderLabel = uilabel(app.Canal2Tab);
            app.DelaySliderLabel.Text = 'Delay';
            app.DelaySlider = uislider(app.Canal2Tab);
            app.DelaySlider.Limits = [0 25];
            app.DelaySlider.ValueChangedFcn = createCallbackFcn(app, @DelaySliderValueChanged, true);
            app.FreqHzEditField_2Label = uilabel(app.Canal2Tab);
            app.FreqHzEditField_2Label.Text = 'Freq. (Hz)';
            app.FreqHzEditField_2 = uieditfield(app.Canal2Tab, 'numeric');
            app.FreqHzEditField_2.ValueChangedFcn = createCallbackFcn(app, @FreqHzEditField_2ValueChanged, true);
            app.FrecuenciadecortebajaEditField_2Label = uilabel(app.Canal2Tab);
            app.FrecuenciadecortebajaEditField_2Label.Text = 'Frecuencia de corte baja';
            app.FrecuenciadecortebajaEditField_2 = uieditfield(app.Canal2Tab, 'numeric');
            app.FrecuenciadecortebajaEditField_2.ValueChangedFcn = createCallbackFcn(app, @FrecuenciadecortebajaEditField_2ValueChanged, true);
            app.AplicarfiltroButton_2 = uibutton(app.Canal2Tab, 'push');
            app.AplicarfiltroButton_2.ButtonPushedFcn = createCallbackFcn(app, @AplicarfiltroButton_2Pushed, true);
            app.AplicarfiltroButton_2.Text = 'Aplicar filtro';
            app.PararaudioButton_2 = uibutton(app.Canal2Tab, 'push');
            app.PararaudioButton_2.ButtonPushedFcn = createCallbackFcn(app, @PararaudioButtonPushed, true);
            app.PararaudioButton_2.Text = 'Parar audio';
            app.GrficasfiltrosTab = uitab(app.TabGroup);
            app.GrficasfiltrosTab.Title = 'Gráficas filtros';
            app.UIAxes = uiaxes(app.GrficasfiltrosTab);
            title(app.UIAxes, 'Filtro canal 1')
            xlabel(app.UIAxes, 'Frecuencia (Hz)')
            ylabel(app.UIAxes, 'Magnitud (dB)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.GrficasfiltrosTab);
            title(app.UIAxes2, 'Filtro canal 2')
            xlabel(app.UIAxes2, 'Frecuencia (Hz)')
            ylabel(app.UIAxes2, 'Magnitud (dB)')
            zlabel(app.UIAxes2, 'Z')
            app.ReproduciroriginalButton_3 = uibutton(app.GrficasfiltrosTab, 'push');
            app.ReproduciroriginalButton_3.ButtonPushedFcn = createCallbackFcn(app, @ReproduciroriginalButtonPushed, true);
            app.ReproduciroriginalButton_3.Text = 'Reproducir original';
            app.ReproducirnuevoButton_3 = uibutton(app.GrficasfiltrosTab, 'push');
            app.ReproducirnuevoButton_3.ButtonPushedFcn = createCallbackFcn(app, @ReproducirnuevoButton_3Pushed, true);
            app.ReproducirnuevoButton_3.Text = 'Reproducir nuevo';
            app.GrficasdeaudioTab = uitab(app.TabGroup);
            app.GrficasdeaudioTab.Title = 'Gráficas de audio';
            app.UIAxes3 = uiaxes(app.GrficasdeaudioTab);
            title(app.UIAxes3, 'Audio original')
            xlabel(app.UIAxes3, 'Frecuencia (Hz)')
            ylabel(app.UIAxes3, 'Magnitud (dB)')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.GrficasdeaudioTab);
            title(app.UIAxes4, 'Audio modificado')
            xlabel(app.UIAxes4, 'Frecuencia (Hz)')
            ylabel(app.UIAxes4, 'Magnitud (dB)')
            zlabel(app.UIAxes4, 'Z')
            app.ReproduciroriginalButton_5 = uibutton(app.GrficasdeaudioTab, 'push');
            app.ReproduciroriginalButton_5.ButtonPushedFcn = createCallbackFcn(app, @ReproduciroriginalButtonPushed, true);
            app.ReproduciroriginalButton_5.Text = 'Reproducir original';
            app.ReproducirnuevoButton_4 = uibutton(app.GrficasdeaudioTab, 'push');
            app.ReproducirnuevoButton_4.Text = 'Reproducir nuevo';
            app.GuardarTab = uitab(app.TabGroup);
            app.GuardarTab.Title = 'Guardar ';
            app.ReproduciroriginalButton_6 = uibutton(app.GuardarTab, 'push');
            app.ReproduciroriginalButton_6.ButtonPushedFcn = createCallbackFcn(app, @ReproduciroriginalButtonPushed, true);
            app.ReproduciroriginalButton_6.Text = 'Reproducir original';
            app.ReproducirnuevoButton_5 = uibutton(app.GuardarTab, 'push');
            app.ReproducirnuevoButton_5.Text = 'Reproducir nuevo';
            app.GuardarnuevocomowavButton = uibutton(app.GuardarTab, 'push');
            app.GuardarnuevocomowavButton.Text = 'Guardar nuevo como .wav';
            app.GuardarvariablescomomatButton = uibutton(app.GuardarTab, 'push');
            app.GuardarvariablescomomatButton.Text = 'Guardar variables como .mat';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Proyecto

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end